classdef ZmapSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZMAPSettingsUIFigure           matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CrossSectionsTab               matlab.ui.container.Tab
        CrossSectionsLabel             matlab.ui.control.Label
        CrossSectionsPanel             matlab.ui.container.Panel
        XsectionWidthkmEditFieldLabel  matlab.ui.control.Label
        XsecWidthEditField             matlab.ui.control.NumericEditField
        ColorDropDownLabel             matlab.ui.control.Label
        XsecColorDropDown              matlab.ui.control.DropDown
        LineWidthEditFieldLabel        matlab.ui.control.Label
        XsecLineWidthEditField         matlab.ui.control.NumericEditField
        LineStyleDropDownLabel         matlab.ui.control.Label
        XsecLineStyleDropDown          matlab.ui.control.DropDown
        EndMarkerDropDownLabel         matlab.ui.control.Label
        XsecEndMarkerDropDown          matlab.ui.control.DropDown
        MarkerSizeEditFieldLabel       matlab.ui.control.Label
        XsecMarkerSizeEditField        matlab.ui.control.NumericEditField
        LabelsPanel                    matlab.ui.container.Panel
        FontSizeEditFieldLabel         matlab.ui.control.Label
        XsecLabelFontSizeEditField     matlab.ui.control.NumericEditField
        BackgroundColorDropDownLabel   matlab.ui.control.Label
        XsecLabelBackgroundColorDropDown  matlab.ui.control.DropDown
        XsecLabelOutlineCheckBox       matlab.ui.control.CheckBox
        XsecLabelBoldCheckBox          matlab.ui.control.CheckBox
        BorderPanel                    matlab.ui.container.Panel
        LineStyleDropDown_2Label       matlab.ui.control.Label
        XsecBorderLineStyleDropDown    matlab.ui.control.DropDown
        LineWidthEditField_2Label      matlab.ui.control.Label
        XsecBorderLineWidthEditField   matlab.ui.control.NumericEditField
        TabGroup3                      matlab.ui.container.TabGroup
        AATab                          matlab.ui.container.Tab
        XSecAxes                       matlab.ui.control.UIAxes
        TabPanel                       matlab.ui.container.Panel
        ColorDropDown_2Label           matlab.ui.control.Label
        XsecTabColorDropDown           matlab.ui.control.DropDown
        GeneralTab                     matlab.ui.container.Tab
        CatalogsTab                    matlab.ui.container.Tab
        MainMapTab                     matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        DisplayOptionsTab              matlab.ui.container.Tab
        FeaturesTab                    matlab.ui.container.Tab
        ResultsTab                     matlab.ui.container.Tab
        SamplingTab                    matlab.ui.container.Tab
        DefaultsTab                    matlab.ui.container.Tab
        SaveButton                     matlab.ui.control.Button
    end


    methods (Access = private)
    
        function XsecInitializeAxes(app)
            zans=struct();
            zans.slicewidth_km=app.XsecWidthEditField;  % slicewidth_km
            zans.startlabel='A';   % startlabel
            zans.endlabel='A''';       % endlabel
            zans.color=app.XSecAxes.ColorOrder(app.XSecAxes.ColorOrderIndex,:); % color
            app.XSecAxes.YLim=[-2 12];
            xlw=(app.XsecWidthEditField.Value .* 1.1) ./ 111;
            app.XSecAxes.XLim=[-xlw, xlw]; 
            xs = XSection(app.XSecAxes, zans, [0 0], [10,0]);
            app.XSecAxes.UserData=xs;
            app.XSecAxes.YLim=[-2 12];
            
        end
        
        function results = saveDefaults(app)
            
            % cross section defaults
            results=struct();
            results.WidthKm = app.XsecWidthEditField.Value;
            results.LineProps = struct(...
                'Marker', app.XsecEndMarkerDropDown.Value,...
                'LineWidth', app.XsecLineWidthEditField.Value,...
                'LineStyle', app.XsecLineStyleDropDown.Value,...
                'Color',app.XsecColorDropDown.Value,...
                'MarkerSize', app.XsecMarkerSizeEditField.Value);
                
            results.LabelProps = struct('FontWeight',app.checkbox2option(app.XsecLabelBoldCheckBox,'bold','normal'),...
                'FontSize',app.XsecLabelFontSizeEditField.Value,...
                'BackgroundColor',app.XsecLabelBackgroundColorDropDown.Value,...
                'EdgeColor',app.checkbox2option(app.XsecLabelOutlineCheckBox,app.XsecColorDropDown.Value,'none')...
                );
            results.TabProps = struct(...
                'Color',app.XsecTabColorDropDown.Value...
                );
            results.BorderProps = struct(...
                'LineStyle',app.XsecBorderLineStyleDropDown.Value,...
                'LineWidth',app.XsecBorderLineWidthEditField.Value...
                );
            defaults.writeDefaults('cross_section',results);


        end
        
        function results = loadDefaults(app)
            
        end
        function val=checkbox2option(app,checkcontrol, iftrue, iffalse)
            if checkcontrol.Value
                val=iftrue;
            else
                val=iffalse;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.XsecInitializeAxes;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.saveDefaults();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZMAPSettingsUIFigure = uifigure;
            app.ZMAPSettingsUIFigure.Name = 'ZMAP Settings';
            app.TabGroup = uitabgroup(app.ZMAPSettingsUIFigure);
            app.CrossSectionsTab = uitab(app.TabGroup);
            app.CrossSectionsTab.Title = 'Cross Sections';
            app.CrossSectionsLabel = uilabel(app.CrossSectionsTab);
            app.CrossSectionsLabel.Text = 'Cross Sections';
            app.CrossSectionsPanel = uipanel(app.CrossSectionsTab);
            app.CrossSectionsPanel.Title = 'Cross Sections';
            app.XsectionWidthkmEditFieldLabel = uilabel(app.CrossSectionsPanel);
            app.XsectionWidthkmEditFieldLabel.Text = 'X-section Width [km]';
            app.XsecWidthEditField = uieditfield(app.CrossSectionsPanel, 'numeric');
            app.XsecWidthEditField.Value = 20;
            app.ColorDropDownLabel = uilabel(app.CrossSectionsPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.XsecColorDropDown = uidropdown(app.CrossSectionsPanel);
            app.XsecColorDropDown.Items = {'AUTO', 'USER DEFINED'};
            app.XsecColorDropDown.Editable = 'on';
            app.XsecColorDropDown.Value = 'AUTO';
            app.LineWidthEditFieldLabel = uilabel(app.CrossSectionsPanel);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.XsecLineWidthEditField = uieditfield(app.CrossSectionsPanel, 'numeric');
            app.XsecLineWidthEditField.LowerLimitInclusive = 'off';
            app.XsecLineWidthEditField.UpperLimitInclusive = 'off';
            app.XsecLineWidthEditField.Limits = [0 Inf];
            app.XsecLineWidthEditField.Value = 3;
            app.LineStyleDropDownLabel = uilabel(app.CrossSectionsPanel);
            app.LineStyleDropDownLabel.Text = 'Line Style';
            app.XsecLineStyleDropDown = uidropdown(app.CrossSectionsPanel);
            app.XsecLineStyleDropDown.Items = {'-', '--', ':', '-.', 'none'};
            app.XsecLineStyleDropDown.Value = '--';
            app.EndMarkerDropDownLabel = uilabel(app.CrossSectionsPanel);
            app.EndMarkerDropDownLabel.Text = 'End Marker';
            app.XsecEndMarkerDropDown = uidropdown(app.CrossSectionsPanel);
            app.XsecEndMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.XsecEndMarkerDropDown.Value = 'x';
            app.MarkerSizeEditFieldLabel = uilabel(app.CrossSectionsPanel);
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
            app.XsecMarkerSizeEditField = uieditfield(app.CrossSectionsPanel, 'numeric');
            app.XsecMarkerSizeEditField.Value = 10;
            app.LabelsPanel = uipanel(app.CrossSectionsTab);
            app.LabelsPanel.Title = 'Labels';
            app.FontSizeEditFieldLabel = uilabel(app.LabelsPanel);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.XsecLabelFontSizeEditField = uieditfield(app.LabelsPanel, 'numeric');
            app.XsecLabelFontSizeEditField.LowerLimitInclusive = 'off';
            app.XsecLabelFontSizeEditField.UpperLimitInclusive = 'off';
            app.XsecLabelFontSizeEditField.Limits = [0 Inf];
            app.XsecLabelFontSizeEditField.Value = 12;
            app.BackgroundColorDropDownLabel = uilabel(app.LabelsPanel);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.XsecLabelBackgroundColorDropDown = uidropdown(app.LabelsPanel);
            app.XsecLabelBackgroundColorDropDown.Items = {'NONE', 'white', 'USER DEFINED'};
            app.XsecLabelBackgroundColorDropDown.Value = 'NONE';
            app.XsecLabelOutlineCheckBox = uicheckbox(app.LabelsPanel);
            app.XsecLabelOutlineCheckBox.Text = 'Outline';
            app.XsecLabelBoldCheckBox = uicheckbox(app.LabelsPanel);
            app.XsecLabelBoldCheckBox.Text = 'Bold';
            app.BorderPanel = uipanel(app.CrossSectionsTab);
            app.BorderPanel.Title = 'Border';
            app.LineStyleDropDown_2Label = uilabel(app.BorderPanel);
            app.LineStyleDropDown_2Label.Text = 'Line Style';
            app.XsecBorderLineStyleDropDown = uidropdown(app.BorderPanel);
            app.XsecBorderLineStyleDropDown.Items = {'-', '--', ':', '-.', 'none'};
            app.XsecBorderLineStyleDropDown.Value = '-';
            app.LineWidthEditField_2Label = uilabel(app.BorderPanel);
            app.LineWidthEditField_2Label.Text = 'Line Width';
            app.XsecBorderLineWidthEditField = uieditfield(app.BorderPanel, 'numeric');
            app.XsecBorderLineWidthEditField.LowerLimitInclusive = 'off';
            app.XsecBorderLineWidthEditField.UpperLimitInclusive = 'off';
            app.XsecBorderLineWidthEditField.Limits = [0 Inf];
            app.XsecBorderLineWidthEditField.Value = 0.25;
            app.TabGroup3 = uitabgroup(app.CrossSectionsTab);
            app.AATab = uitab(app.TabGroup3);
            app.AATab.Title = 'A - A''';
            app.XSecAxes = uiaxes(app.AATab);
            title(app.XSecAxes, 'A-A''')
            xlabel(app.XSecAxes, 'Perpendicular to X-Section')
            ylabel(app.XSecAxes, 'Distance Along Cros Section')
            app.TabPanel = uipanel(app.CrossSectionsTab);
            app.TabPanel.Title = 'Tab';
            app.ColorDropDown_2Label = uilabel(app.TabPanel);
            app.ColorDropDown_2Label.Text = 'Color';
            app.XsecTabColorDropDown = uidropdown(app.TabPanel);
            app.XsecTabColorDropDown.Items = {'AUTO', 'Black', 'Darker', 'Lighter', 'USER DEFINED'};
            app.XsecTabColorDropDown.Editable = 'on';
            app.XsecTabColorDropDown.Value = 'Darker';
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.CatalogsTab = uitab(app.TabGroup);
            app.CatalogsTab.Title = 'Catalogs';
            app.MainMapTab = uitab(app.TabGroup);
            app.MainMapTab.Title = 'Main Map';
            app.TabGroup2 = uitabgroup(app.MainMapTab);
            app.DisplayOptionsTab = uitab(app.TabGroup2);
            app.DisplayOptionsTab.Title = 'Display Options';
            app.FeaturesTab = uitab(app.TabGroup2);
            app.FeaturesTab.Title = 'Features';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.SamplingTab = uitab(app.TabGroup);
            app.SamplingTab.Title = 'Sampling';
            app.DefaultsTab = uitab(app.TabGroup);
            app.DefaultsTab.Title = 'Defaults';
            app.SaveButton = uibutton(app.ZMAPSettingsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ZmapSettings

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZMAPSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZMAPSettingsUIFigure)
        end
    end
end