classdef grid_chooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChooseGridParametersUIFigure  matlab.ui.Figure
        GridNameEditFieldLabel        matlab.ui.control.Label
        GridNameEditField             matlab.ui.control.EditField
        AnchorPointPanel              matlab.ui.container.Panel
        XorLonEditFieldLabel          matlab.ui.control.Label
        XAnchor                       matlab.ui.control.NumericEditField
        YorLatEditFieldLabel          matlab.ui.control.Label
        YAnchor                       matlab.ui.control.NumericEditField
        ZdownEditFieldLabel           matlab.ui.control.Label
        ZAnchor                       matlab.ui.control.NumericEditField
        SeparationPanel               matlab.ui.container.Panel
        dxEditFieldLabel              matlab.ui.control.Label
        dx                            matlab.ui.control.NumericEditField
        dyEditFieldLabel              matlab.ui.control.Label
        dy                            matlab.ui.control.NumericEditField
        dzkmLabel                     matlab.ui.control.Label
        dz                            matlab.ui.control.NumericEditField
        UnitsDropDownLabel            matlab.ui.control.Label
        UnitsDropDown                 matlab.ui.control.DropDown
        FollowMeridiansCheckBox       matlab.ui.control.CheckBox
        SetButton                     matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        AnchorPoint % Description
        GridPoints % Description
    end

    methods (Access = private)
    
        function [lonMat, latMat] = get_eq_grid(app)
            lon0 = app.XAnchor.Value;
            lat0 = app.YAnchor.Value;
            dLon = app.dx.Value;
            dLat = app.dy.Value;
            % GET_EQ_GRID
            % input is the origin point and arclength between points
            % output is 2 matrices (lon, lat)
            
            if app.UnitsDropDown.Value == "Kilometers"
                dLon = km2deg(dLon);
                dLat = km2deg(dLat);
            end
                
            % use the axes limits (assumed degrees) to control size of grid
            ylims_deg = app.UIAxes.YLim;
            xlims_deg = app.UIAxes.XLim;
            
            % pick out latitude spacing. Our grid will have this many rows.
            lats = app.vector_including_origin(lat0, dLat, ylims_deg);
            lonMat=[];
            latMat=[];
            
            if app.FollowMeridiansCheckBox.Visible=="on" &&  app.FollowMeridiansCheckBox.Value
                % when following the meridian lines, the longitude span covered by
                % the arc-distance at lat0 (along the rhumb!) remains constant.
                % that is, dLon 45 from origin (0,0) will always be 45, regardless of latitude.
                [~,dLon]=reckon('rh',lat0,0,dLon,90); %dLon was dist_arc
                
                % resulting in a rectangular matrix where, on a globe lines will converge, but on a graph
                lonValues = app.vector_including_origin(lon0, dLon, xlims_deg);
                
                %creates a meshgrid of size numel(lonValues) x numel(lats)
                [lonMat,latMat]=meshgrid(lonValues,lats);
                
            else
                % when ignoring meridian lines, and aiming for an approximately constant distance,
                % the dLon at each latitude will differ.
                
                % number of degrees longitude covered by the arclength at each latitude
                [~,dLon_per_lat]=reckon('rh',lats,0,dLon,90); %dLon was dist_arc
                
                for n=1:numel(lats)
                    theseLonValues = app.vector_including_origin(lon0, dLon_per_lat(n), xlims_deg);
                    lonMat=[lonMat;theseLonValues(:)]; %#ok<AGROW>
                    latMat=[latMat;repmat(lats(n),size(theseLonValues(:)))]; %#ok<AGROW>
                end
                
                [lonMat,latMat] = app.cols2matrix(lonMat,latMat);
                % each gridx & gridy are vectors.
            end
            
        end
        
        
        function [xs, ys] = cols2matrix(app,lonCol,latCol)
            % COLS2MATRIX convert columns of lats & lons into a matrix.
            % this takes lots of stuff into account
            %
            
            % assign pgrid
            ugy=unique(latCol); % lats in matrix
            nrows=numel(ugy); % number of latitudes in matrix
            [~,example]=min(abs(latCol(:))); % latitude closest to equator will have most number of lons in matrix
            mostCommonY=latCol(example); % account for the abs possibly flipping signs
            base_lon_idx=find(lonCol(latCol==mostCommonY)==app.XAnchor.Value); % longitudes that must line up
            ncols=sum(latCol(:)==mostCommonY); % most number of lons in matrix
            ys=repmat(ugy(:),1,ncols);
            xs=nan(nrows,ncols);
            for n=1:nrows
                thislat=ugy(n); % lat for this row
                idx_lons=(latCol==thislat); % mask of lons in this row
                these_lons=lonCol(idx_lons); % lons in this row
                row_length=numel(these_lons); % number of lons in this row
                
                main_lon_idx=find(these_lons==app.XAnchor.Value); % offset of X in this row
                offset=base_lon_idx - main_lon_idx;
                xs(n,(1:row_length)+offset)=these_lons;
            end
        end
            
        function v = vector_including_origin(~, orig_deg, delta_deg, lims_deg)
            v = unique([orig_deg : -delta_deg : min(lims_deg) , orig_deg : delta_deg :max(lims_deg)]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ZG = ZmapGlobal.Data;
            
            gridopt = ZG.gridopt;
            app.dx.Value=gridopt.dx;
            app.dy.Value=gridopt.dy;
            app.dz.Value=gridopt.dz;
            
            if app.UnitsDropDown.Value == "Degrees"
                app.FollowMeridiansCheckBox.Visible = 'on';
            else
                app.FollowMeridiansCheckBox.Visible = 'off';
            end
            if ~isempty(ZG.primeCatalog)
                app.UIAxes.XLim=[min(ZG.primeCatalog.Longitude) max(ZG.primeCatalog.Longitude)];
                app.UIAxes.YLim=[min(ZG.primeCatalog.Latitude) max(ZG.primeCatalog.Latitude)];
            else
                app.UIAxes.XLim=[-180 180];
                app.UIAxes.YLim=[-90 90];
            end
            
            g =ZG.Grid;
            if ~isempty(g.Name)
                app.GridNameEditField.Value=g.Name;
            end
            if ~isempty(g.Origin)
                app.XAnchor.Value=origin(1);
                app.YAnchor.Value=origin(2);
                if length(g.Origin)==3
                    app.ZAnchor.Value=origin(3);
                end
            else
                app.XAnchor.Value=mean(app.UIAxes.XLim);
                app.YAnchor.Value=mean(app.UIAxes.YLim);
            end
            
            app.AnchorPoint = scatter(app.UIAxes, app.XAnchor.Value, app.YAnchor.Value,100,'x','LineWidth',2);
            app.AnchorPointPanel.ForegroundColor=app.AnchorPoint.CData;
            
            hold(app.UIAxes,'on');

            [lonMat, latMat] = app.get_eq_grid;
            app.GridPoints = scatter(app.UIAxes, lonMat(:), latMat(:), 12,'+','LineWidth',1);
            
            hold(app.UIAxes,'off');
                
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            value = app.UnitsDropDown.Value;
            if value == "Degrees"
                app.FollowMeridiansCheckBox.Visible = 'on';
            else
                app.FollowMeridiansCheckBox.Visible = 'off';
            end
            app.UpdateGridPoints
        end

        % Value changed function: XAnchor, YAnchor
        function UpdateAnchorPoint(app, event)
            app.AnchorPoint.XData = app.XAnchor.Value;
            app.AnchorPoint.YData = app.YAnchor.Value;
            app.UpdateGridPoints;
        end

        % Value changed function: FollowMeridiansCheckBox, dx, dy
        function UpdateGridPoints(app, event)
            [lonMat, latMat] = app.get_eq_grid;
            app.GridPoints.XData=lonMat(:);
            app.GridPoints.YData=latMat(:);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            gridopt = struct();
            gridopt.dx = app.dx.Value;
            if app.UnitsDropDown.Value == "Degrees"
                gridopt.dx_units = 'deg';
            else
                gridopt.dx_units = 'km';
            end
            gridopt.dy = app.dy.Value;
            gridopt.dy_units = gridopt.dx_units;
            gridopt.dz = app.dz.Value;
            gridopt.dz_units = 'km';
            
            ZG = ZmapGlobal.Data;
            ZG.gridopt = gridopt;
            
            g = ZmapGrid(app.GridNameEditField.Value,... name
                [app.XAnchor.Value, app.YAnchor.Value], ... origin_degs
                [app.dx.Value, app.dy.Value], ... delta_degs
                [app.UIAxes.XLim ; app.UIAxes.YLim],...limits_degs
                app.FollowMeridiansCheckBox.Value); % follow_meridians
            ZG.Grid = g;
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChooseGridParametersUIFigure = uifigure;
            app.ChooseGridParametersUIFigure.Name = 'Choose Grid Parameters';
            app.GridNameEditFieldLabel = uilabel(app.ChooseGridParametersUIFigure);
            app.GridNameEditFieldLabel.Text = 'Grid Name';
            app.GridNameEditField = uieditfield(app.ChooseGridParametersUIFigure, 'text');
            app.GridNameEditField.Value = 'grid';
            app.AnchorPointPanel = uipanel(app.ChooseGridParametersUIFigure);
            app.AnchorPointPanel.Title = 'Anchor Point';
            app.XorLonEditFieldLabel = uilabel(app.AnchorPointPanel);
            app.XorLonEditFieldLabel.Text = 'X or Lon';
            app.XAnchor = uieditfield(app.AnchorPointPanel, 'numeric');
            app.XAnchor.ValueChangedFcn = createCallbackFcn(app, @UpdateAnchorPoint, true);
            app.YorLatEditFieldLabel = uilabel(app.AnchorPointPanel);
            app.YorLatEditFieldLabel.Text = 'Y or Lat';
            app.YAnchor = uieditfield(app.AnchorPointPanel, 'numeric');
            app.YAnchor.ValueChangedFcn = createCallbackFcn(app, @UpdateAnchorPoint, true);
            app.ZdownEditFieldLabel = uilabel(app.AnchorPointPanel);
            app.ZdownEditFieldLabel.Enable = 'off';
            app.ZdownEditFieldLabel.Text = 'Z (+down)';
            app.ZAnchor = uieditfield(app.AnchorPointPanel, 'numeric');
            app.ZAnchor.Enable = 'off';
            app.SeparationPanel = uipanel(app.ChooseGridParametersUIFigure);
            app.SeparationPanel.Title = 'Separation';
            app.dxEditFieldLabel = uilabel(app.SeparationPanel);
            app.dxEditFieldLabel.Text = 'dx';
            app.dx = uieditfield(app.SeparationPanel, 'numeric');
            app.dx.ValueChangedFcn = createCallbackFcn(app, @UpdateGridPoints, true);
            app.dyEditFieldLabel = uilabel(app.SeparationPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.dy = uieditfield(app.SeparationPanel, 'numeric');
            app.dy.ValueChangedFcn = createCallbackFcn(app, @UpdateGridPoints, true);
            app.dzkmLabel = uilabel(app.SeparationPanel);
            app.dzkmLabel.Enable = 'off';
            app.dzkmLabel.Text = 'dz [km]';
            app.dz = uieditfield(app.SeparationPanel, 'numeric');
            app.dz.Enable = 'off';
            app.UnitsDropDownLabel = uilabel(app.SeparationPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.SeparationPanel);
            app.UnitsDropDown.Items = {'Degrees', 'Kilometers'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'Degrees';
            app.FollowMeridiansCheckBox = uicheckbox(app.SeparationPanel);
            app.FollowMeridiansCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateGridPoints, true);
            app.FollowMeridiansCheckBox.Text = 'Follow Meridians';
            app.SetButton = uibutton(app.ChooseGridParametersUIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.UIAxes = uiaxes(app.ChooseGridParametersUIFigure);
            title(app.UIAxes, 'Sample Grid Spacing')
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
        end
    end

    methods (Access = public)

        % Construct app
        function app = grid_chooser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChooseGridParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChooseGridParametersUIFigure)
        end
    end
end